classdef BT_Detector < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SegmentButton              matlab.ui.control.Button
        DetectButton               matlab.ui.control.Button
        ModelInfoLabel             matlab.ui.control.Label
        BeyinTmrTanUygulamasLabel  matlab.ui.control.Label
        ModelDropDown              matlab.ui.control.DropDown
        Label_2                    matlab.ui.control.Label
        TahminLabel                matlab.ui.control.Label
        ClassifyButton             matlab.ui.control.Button
        LoadButton                 matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ImagePath  % Seçilen görselin dosya yolunu burada tutacağız
        YOLOv2Model % YOLOv2 tespit modeli
        SelectedImage  % Kullanıcının seçtiği resmi burada saklayacağız
    end
    
    methods (Access = private)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             % YOLOv2 modelini yükle
     s = load(fullfile(pwd,"trained_darknet_yolov2.mat"));
    app.YOLOv2Model = s.detector;
    addpath(fileparts(which("quickMaskROI.m")));
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
[file, path] = uigetfile({'*.jpg;*.jpeg;*.png','Image Files (*.jpg, *.jpeg, *.png)'});
    if isequal(file, 0)
        return; % Dosya seçilmediyse çık
    end

    imgPath = fullfile(path, file);
    img = imread(imgPath);

    % ---- Griye çevirmiyoruz, orijinal renkli şekilde bırakıyoruz!

    imshow(img, 'Parent', app.UIAxes);

    app.SelectedImage = img;
    app.ImagePath = imgPath;  % YOLOv2 için dosya yolunu kaydet


        end

        % Button pushed function: ClassifyButton
        function ClassifyButtonPushed(app, event)
if isempty(app.SelectedImage)
        uialert(app.UIFigure, 'Lütfen önce bir resim seçin.', 'Uyarı');
        return;
    end

    selectedModel = app.ModelDropDown.Value;

switch selectedModel
    case 'CNN - Epoch20'
        load('brain_tumor_cnn_epoch20.mat', 'cnnModel');
        infoText = "Validasyon: %94.59 | Test: %89.47";

    case 'GoogleNet'
    load('trainedGoogleNetModel.mat');  % sadece dosyayı yükle
    cnnModel = googlenetModel;          % dosyadaki doğru isme ata
    infoText = "Validasyon: %94.59 | Test: %94.73";

    case 'MobileNetV2'
        load('trainedMobileNetModel.mat', 'mobilenetModel');
        cnnModel = mobilenetModel;
        infoText = "Validasyon: %91.89 | Test: %89.47";

    case 'ResNet50'
        load('trainedResNetModel.mat', 'resnetModel');
        cnnModel = resnetModel;
        infoText = "Validasyon: %92.11 | Test: %86.49";

    otherwise
        uialert(app.UIFigure, 'Geçerli bir model seçilmedi.', 'Uyarı');
        return;
end

% 🎯 BURASI ÖNEMLİ: Label'a yaz
app.ModelInfoLabel.Text = infoText;

    inputSize = cnnModel.Layers(1).InputSize;
    img = imresize(app.SelectedImage, inputSize(1:2));

if size(img, 3) == 1
    img = cat(3, img, img, img);
end

    YPred = classify(cnnModel, img);
app.TahminLabel.Text = ['Tahmin: ', char(YPred)];

% 🌿 RENK AYARI
if strcmpi(char(YPred), 'no')
    app.TahminLabel.FontColor = [0 0.6 0];  % yeşil
elseif strcmpi(char(YPred), 'yes')
    app.TahminLabel.FontColor = [1 0 0];  % yeşil
else
    app.TahminLabel.FontColor = [0 0 0];    % bilinmeyen için siyah
end
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

            % Grafik etiketlerini kaldır
    xlabel(app.UIAxes, '');
    ylabel(app.UIAxes, '');
    title(app.UIAxes, '');
        end

        % Button pushed function: DetectButton
        function DetectButtonPushed(app, event)
            if isempty(app.ImagePath)
        uialert(app.UIFigure, "Lütfen önce bir resim seçin.", "Uyarı");
        return;
    end

    % Her zaman 3-kanallı oku!
    I = convertTo3Channel(imread(app.ImagePath));
    [bboxes, scores, labels] = detect(app.YOLOv2Model, I, 'Threshold', 0.5);

    % Eşik uygula
    idx = scores > 0.5;
    bboxes = bboxes(idx, :);
    scores = scores(idx);
    labels = labels(idx);

    % Anotasyon
    if ~isempty(bboxes)
        I_annotated = insertObjectAnnotation(I, 'Rectangle', bboxes, scores);
    else
        I_annotated = I;
        disp("⚠️ Tespit edilen tümör yok (threshold yüksek olabilir).");
    end

    imshow(I_annotated, 'Parent', app.UIAxes);
    title(app.UIAxes, "YOLOv2 Tespit Sonucu");
        end

        % Button pushed function: SegmentButton
        function SegmentButtonPushed(app, event)
      if isempty(app.ImagePath)
        uialert(app.UIFigure,"Önce görüntü seç.","Uyarı");  return;
    end

    % 1) YİNE 3 kanal olarak oku!
    I = convertTo3Channel(imread(app.ImagePath));

    % 2) YOLO tespit (3 kanallı giriş ZORUNLU)
    det = app.YOLOv2Model;
    [bboxes, scores, labels] = detect(app.YOLOv2Model, I, 'Threshold', 0.3);  % I üç kanallı olmalı!
if isempty(bboxes)
    uialert(app.UIFigure,"Tümör kutusu bulunamadı.","Bilgi"); return;
end
box = bboxes(1,:);   % tespit edilen ilk kutu
roi = imcrop(I, box);  % dikkat: I üç kanallı olmalı!

    % 3) ROI'yi KES (RGB) ve GRİ'YE çevir
    roi = imcrop(I, box);
    roiGray = rgb2gray(roi);

    % 4) Morfolojik segmentasyon
    maskSmall = quickMaskROI(roiGray);      % 2B maske (küçük ROI boyutunda)
    mask = imresize(maskSmall, [box(4) box(3)]);

    % 5) Maskeyi tüm görüntüye YERLEŞTİR
    fullMask = false(size(I,1), size(I,2));
    fullMask(box(2):(box(2)+box(4)-1), box(1):(box(1)+box(3)-1)) = mask;

    % 6) Overlay ve Rectangle ile göster
    over = labeloverlay(I, fullMask, 'Transparency',0.5,'Colormap','winter');
    over = insertShape(over, 'Rectangle', box, 'Color','yellow','LineWidth',2);

    imshow(over,'Parent',app.UIAxes);
    title(app.UIAxes,"Morfolojik Segmentasyon",'Color','b');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Resim Seç';
            app.ClassifyButton = uibutton(app.UIFigure, 'push');
            app.ClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyButtonPushed, true);
            app.ClassifyButton.Text = 'Sınıflandır';
            app.TahminLabel = uilabel(app.UIFigure);
            app.TahminLabel.Text = 'Tahmin:';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Model Tipi';
            app.ModelDropDown = uidropdown(app.UIFigure);
            app.ModelDropDown.Items = {'CNN - Epoch20', 'GoogleNet', 'MobileNetV2', 'ResNet50'};
            app.ModelDropDown.Value = 'CNN - Epoch20';
            app.BeyinTmrTanUygulamasLabel = uilabel(app.UIFigure);
            app.BeyinTmrTanUygulamasLabel.Text = 'Beyin Tümörü Tanı Uygulaması';
            app.ModelInfoLabel = uilabel(app.UIFigure);
            app.ModelInfoLabel.Text = 'Model Bilgisi:';
            app.DetectButton = uibutton(app.UIFigure, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButtonPushed, true);
            app.DetectButton.Text = 'YOLOv2 Tespit';
            app.SegmentButton = uibutton(app.UIFigure, 'push');
            app.SegmentButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentButtonPushed, true);
            app.SegmentButton.Text = 'Segmantasyon';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BT_Detector

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end